classdef P21_UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        VisualDropDown       matlab.ui.control.DropDown
        VisualDropDownLabel  matlab.ui.control.Label
        UITable              matlab.ui.control.Table
        StateLabel           matlab.ui.control.Label
        ExportResultsButton  matlab.ui.control.Button
        StopButton           matlab.ui.control.Button
        Image                matlab.ui.control.Image
        StartButton          matlab.ui.control.Button
    end

    
    properties (Access = private)
        StopFlag = false % Description
        MC_reading_azemuth % Description
        SDR_reading % Description
        MC_reading_elevation % Description
        ExName = "Data/Result.xlsx" % Description
        ValueTable % Description
        currentFolder %Used to hold the location of the current directory
        
    end
    
    methods (Access = private)

        function InitializeEnvironment(app)
            %system("pip install Pillow");
            app.currentFolder = pwd;
            
            disp(app.currentFolder);

            
        end
        
        function results = DataCollection(app)
            
            app.ValueTable = table("Magnitude","Elevation","Azimuth");
            vars = ["Magnitude","Elevation","Azimuth"];

            TableData = get(app.UITable,'data');


            while ~app.StopFlag

                app.MC_reading_azemuth = string(pyrunfile("PythonScripts/GetMcReadingAzemuth.py","azimuth"));
                app.MC_reading_elevation = string(pyrunfile("PythonScripts/GetMcReadingElevation.py","elevation"));
                %app.SDR_reading = pyrunfile("PythonScripts/GetSdrReading.py","res");
                app.SDR_reading = "1";

                %disp(app.SDR_reading);
                %disp(app.MC_reading_elevation);
                %disp(app.MC_reading_azemuth);

                currentReading = {app.SDR_reading, app.MC_reading_elevation, app.MC_reading_azemuth};

                app.ValueTable(end+1,:) = currentReading;
                TableData(end+1,:) = [str2double(app.SDR_reading), str2double(app.MC_reading_elevation), str2double(app.MC_reading_azemuth)];

                pause(0.001)

            end

            set(app.UITable,'data',TableData);


            %create Directory if needed 
            if ~exist("Data", "dir")
                mkdir("Data")
            end

            writetable(app.ValueTable,app.ExName);

            imageExecutablePath = app.currentFolder + "/Executables/ToImage";

            system(imageExecutablePath);

            app.Image.ImageSource = imread("Data/Image.png");

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Setting up the visibility for the window to defaut to image
            app.UITable.Visible = 'off';
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            %TD add log for stopping the current recording, Sam C
            pyrunfile("PythonScripts/StopScan.py");
            
            app.StopFlag = true;
            app.StateLabel.Text = "Stopped";

            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            %TD add log and function to start the recording Process, Sam C
            pyenv
            InitializeEnvironment(app);

            pyrunfile("PythonScripts/StartScan.py");
            app.StateLabel.Text = "Running";
            app.StopFlag = false;

            DataCollection(app);
          
        end

        % Button pushed function: ExportResultsButton
        function ExportResultsButtonPushed(app, event)
            %TD add a Log for the button press and implement functionality
            %for the user to save the resulting image to a folder location,
            %Sam C
            %pyrunfile("PythonScripts/Export.py")
            app.StateLabel.Text = "Exporting";

            view = app.VisualDropDown.Value;

            switch view
                case 'Image'

                    image = imread("Data/Image.png");

                    [filename,location] = uiputfile({'*.png','*.jpg'});

                    f = fullfile(location,filename );

                    imwrite(image,f );

                    %exportExecutablePath = app.currentFolder + "/Executables/Export";
                    %system(exportExecutablePath);
                case 'Table'
                    dpath = uigetdir('C:\');
                    dpath = dpath + "\exportedTable.xlsx";

                    writematrix(app.UITable.Data,dpath);

                otherwise
                    disp("Value not known");
            end

            
            app.StateLabel.Text = "Done";


        end

        % Value changed function: VisualDropDown
        function VisualDropDownValueChanged(app, event)
            value = app.VisualDropDown.Value;
            disp(value);
            switch value
                case 'Image'
                    app.UITable.Visible = "off";
                    app.Image.Visible = "on";
                case 'Table'
                    app.UITable.Visible = "on";
                    app.Image.Visible = "off";
                otherwise
                    disp("Value not Known");
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Logo.png');
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.ExportResultsButton = uibutton(app.UIFigure, 'push');
            app.ExportResultsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportResultsButtonPushed, true);
            app.ExportResultsButton.Text = 'Export Results';
            app.StateLabel = uilabel(app.UIFigure);
            app.StateLabel.Text = 'State';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Magnitude'; 'Elevation'; 'Azimuth'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.VisualDropDownLabel = uilabel(app.UIFigure);
            app.VisualDropDownLabel.Text = 'Visual';
            app.VisualDropDown = uidropdown(app.UIFigure);
            app.VisualDropDown.Items = {'Table', 'Image'};
            app.VisualDropDown.ValueChangedFcn = createCallbackFcn(app, @VisualDropDownValueChanged, true);
            app.VisualDropDown.Value = 'Image';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = P21_UI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end